classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        AregularcylindricalworkspacewithadiameterofLabel  matlab.ui.control.Label
        ProblemParametersandconstraintsLabel  matlab.ui.control.Label
        workspaceDiameter              matlab.ui.control.EditField
        ConstraintsLabel               matlab.ui.control.Label
        WorkspaceLabel                 matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        cond                           matlab.ui.control.CheckBox
        lowerLimit                     matlab.ui.control.CheckBox
        hightLimit                     matlab.ui.control.CheckBox
        considerGeometricConstraints   matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        diameter_2                     matlab.ui.control.EditField
        traverslespacedetravailLabel   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        lowerLimitValue                matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
        higherLimitValue               matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        WorkspaceSamplingLabel         matlab.ui.control.Label
        MobileplatformerotationrangeLabel  matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        Spinner_2                      matlab.ui.control.Spinner
        toLabel                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        SampletheworkspaceintoLabel    matlab.ui.control.Label
        cerclesandLabel                matlab.ui.control.Label
        equallyspacedsudivisionsLabel  matlab.ui.control.Label
        ncercles                       matlab.ui.control.Spinner
        ndivs                          matlab.ui.control.Spinner
        samplingPreview                matlab.ui.control.UIAxes
        OptimisationTab                matlab.ui.container.Tab
        OptimisationparametersLabel    matlab.ui.control.Label
        ThesystemstatecontainsDdLabel  matlab.ui.control.Label
        LowerboundsPanel               matlab.ui.container.Panel
        DLabel                         matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        lb_D                           matlab.ui.control.NumericEditField
        mLabel_2                       matlab.ui.control.Label
        lb_d                           matlab.ui.control.NumericEditField
        dLabel                         matlab.ui.control.Label
        UpperboundsPanel               matlab.ui.container.Panel
        DLabel_2                       matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        ub_D                           matlab.ui.control.NumericEditField
        mLabel_6                       matlab.ui.control.Label
        ub_d                           matlab.ui.control.NumericEditField
        dLabel_2                       matlab.ui.control.Label
        InitialStatePanel              matlab.ui.container.Panel
        DLabel_3                       matlab.ui.control.Label
        mLabel_9                       matlab.ui.control.Label
        D0                             matlab.ui.control.NumericEditField
        mLabel_10                      matlab.ui.control.Label
        d0                             matlab.ui.control.NumericEditField
        dLabel_3                       matlab.ui.control.Label
        MaxiterationsLabel             matlab.ui.control.Label
        MaxfunctionevaluationLabel     matlab.ui.control.Label
        MaxIterations                  matlab.ui.control.NumericEditField
        MaxEvals                       matlab.ui.control.NumericEditField
        LogIterations                  matlab.ui.control.CheckBox
        Label_7                        matlab.ui.control.Label
        RunOptimisationButton          matlab.ui.control.Button
        Running                        matlab.ui.control.Lamp
        ResultsTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
        UIAxes2_2                      matlab.ui.control.UIAxes
        ExitStatus                     matlab.ui.control.Lamp
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        Slider_2Label                  matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        KnobLabel                      matlab.ui.control.Label
        Knob                           matlab.ui.control.Knob
        ExitFlag                       matlab.ui.control.Label
        OptimaleConfigurationPanel     matlab.ui.container.Panel
        DLabel_4                       matlab.ui.control.Label
        mLabel_13                      matlab.ui.control.Label
        X_D                            matlab.ui.control.NumericEditField
        mLabel_14                      matlab.ui.control.Label
        X_d                            matlab.ui.control.NumericEditField
        dLabel_4                       matlab.ui.control.Label
        CurrentPositionLabel           matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        EditField_19                   matlab.ui.control.NumericEditField
        cmLabel                        matlab.ui.control.Label
        cmLabel_2                      matlab.ui.control.Label
        EditField_20                   matlab.ui.control.NumericEditField
        XLabel_2                       matlab.ui.control.Label
        cmLabel_3                      matlab.ui.control.Label
        EditField_21                   matlab.ui.control.NumericEditField
        XLabel_3                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        runningOptimisation = false; % Description
        pointsList = []; % Description
    end
    
    methods (Access = private)
        function [points_x, points_y] = generateCerclePoints(~, diameter, ndivs)
            points_x = zeros([1 ndivs]);
            points_y = zeros([1 ndivs]);
            angles = linspace(0,2*pi,ndivs);
            for i=1:ndivs
                points_x(i) = (diameter/2) * cos(angles(i));
                points_y(i) = (diameter/2) * sin(angles(i));
            end
        end
        function [points_x, points_y] = generatePoints(app, diameter, n_cercles, ndivs)
            points_x = [0];
            points_y = [0];
            r = linspace(0,diameter/2, n_cercles);
            for i=1:n_cercles
              [xs, ys] = generateCerclePoints(app, r(i)*2, ndivs);
              points_x = [points_x, xs];
              points_y = [points_y, ys];
            end
            % Store pointsList
            app.pointsList = [];
            for i=1:length(points_x)
                app.pointsList = [app.pointsList, [points_x(i);points_y(i)]];
            end
        end
        function updateSamplingPreview(app)
            n_cercles = app.ncercles.Value + 1;
            n_divs = app.ndivs.Value + 1;
            diameter = str2double(app.workspaceDiameter.Value);
            [points_x, points_y] = generatePoints(app, diameter, n_cercles, n_divs);
            cla(app.samplingPreview)
            hold(app.samplingPreview,'on')
            rectangle(app.samplingPreview, 'Position',[-diameter/2,-diameter/2,diameter,diameter],'Curvature',[1 1]);
            plot(app.samplingPreview, points_x, points_y,'r*');
            hold(app.samplingPreview,'off')
            axis equal;
        end
        
        function toggleOptimisation(app, status)
            app.runningOptimisation = status;
            if status
                % Start
                app.Running.Enable = 'on';
                app.Running.Color = '0.00,1.00,0.00';
            else
                % Stop optimization
                app.Running.Color = '0.90,0.90,0.90';
                app.Running.Enable = 'off';
            end
        end
        
        function runOptimisation(app)
            % global parameters
            global conditionnement pointsList;
            conditionnement = 0.2;
            pointsList = app.pointsList;
            
            % initial conditions
            x0 =[app.D0.Value; app.d0.Value]; % D d
            lb =[app.lb_D.Value; app.lb_d.Value];
            ub =[app.ub_D.Value; app.ub_d.Value];
            app.LogIterations.Value
            options = optimset('Display','iter', 'MaxIter', app.MaxIterations.Value,'MaxFunEvals', app.MaxEvals.Value, 'TolFun',1e-8, 'TolX',1e-8);
            % if app.LogIterations.Value
            [X,~,EXITFLAG] = fmincon('objectiveFun', x0, [], [], [], [], lb, ub , 'nonlcong', options);
            setExitFlag(app, EXITFLAG);
            app.X_D.Value = X(1);
            app.X_d.Value = X(2);
        end
        
        function setExitFlag(app, flag)
            app.ExitStatus.Enable = "on";
            switch flag
                case 1
                    app.ExitFlag.Text = "First order optimality conditions satisfied.";
                    app.ExitStatus.Color = "0.00,1.00,0.00";
                case 0
                    app.ExitFlag.Text = "Too many function evaluations or iterations.";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
                case -1
                    app.ExitFlag.Text = "Stopped by output/plot function.";
                    app.ExitStatus.Color = "0.93,0.69,0.13";
                case -2
                    app.ExitFlag.Text = "No feasible point found.";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
                case 2
                    app.ExitFlag.Text = "Change in X too small.";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
                case 3
                    app.ExitFlag.Text = "Change in objective function too small.";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
                case 4
                    app.ExitFlag.Text = "Computed search direction too small.";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
                case 5
                    app.ExitFlag.Text = "Predicted change in objective function too small.";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
                case -3
                    app.ExitFlag.Text = "Problem seems unbounded.";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
                otherwise
                    app.ExitFlag.Text = "Unknown flag";
                    app.ExitStatus.Color = "0.85,0.33,0.10";
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Call update sampling function
            updateSamplingPreview(app);
        end

        % Value changed function: ncercles
        function ncerclesValueChanged(app, event)
            % Call update sampling function
            updateSamplingPreview(app);
        end

        % Value changed function: ndivs
        function ndivsValueChanged(app, event)
            % Call update sampling function
            updateSamplingPreview(app);           
        end

        % Value changed function: workspaceDiameter
        function workspaceDiameterValueChanged(app, event)
            % Call update sampling function
            updateSamplingPreview(app);
        end

        % Button pushed function: RunOptimisationButton
        function RunOptimisationButtonPushed(app, event)
            if app.runningOptimisation
                % Stop optimization
                toggleOptimisation(app, false)
            else
                % Start
                toggleOptimisation(app, true)
                runOptimisation(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.AregularcylindricalworkspacewithadiameterofLabel = uilabel(app.ParametersTab);
            app.AregularcylindricalworkspacewithadiameterofLabel.Text = 'A regular cylindrical workspace with a diameter of ';
            app.ProblemParametersandconstraintsLabel = uilabel(app.ParametersTab);
            app.ProblemParametersandconstraintsLabel.Text = 'Problem Parameters and constraints';
            app.workspaceDiameter = uieditfield(app.ParametersTab, 'text');
            app.workspaceDiameter.ValueChangedFcn = createCallbackFcn(app, @workspaceDiameterValueChanged, true);
            app.workspaceDiameter.Tooltip = {'in mm.'};
            app.workspaceDiameter.Value = '100';
            app.ConstraintsLabel = uilabel(app.ParametersTab);
            app.ConstraintsLabel.Text = 'Constraints:';
            app.WorkspaceLabel = uilabel(app.ParametersTab);
            app.WorkspaceLabel.Text = 'Workspace:';
            app.mmLabel = uilabel(app.ParametersTab);
            app.mmLabel.Text = 'mm.';
            app.cond = uicheckbox(app.ParametersTab);
            app.cond.Text = '';
            app.cond.Value = true;
            app.lowerLimit = uicheckbox(app.ParametersTab);
            app.lowerLimit.Text = '';
            app.lowerLimit.Value = true;
            app.hightLimit = uicheckbox(app.ParametersTab);
            app.hightLimit.Text = '';
            app.hightLimit.Value = true;
            app.considerGeometricConstraints = uicheckbox(app.ParametersTab);
            app.considerGeometricConstraints.Text = '';
            app.considerGeometricConstraints.Value = true;
            app.Label = uilabel(app.ParametersTab);
            app.Label.Text = 'Le conditionnement inverse des Jacobiennes cinématique doit être supérieur à ';
            app.diameter_2 = uieditfield(app.ParametersTab, 'text');
            app.diameter_2.Tooltip = {'in mm.'};
            app.diameter_2.Value = '100';
            app.traverslespacedetravailLabel = uilabel(app.ParametersTab);
            app.traverslespacedetravailLabel.Text = 'à travers l''espace de travail. ';
            app.Label_2 = uilabel(app.ParametersTab);
            app.Label_2.Text = 'La limite inférieur du déplacement des liaisons prismatiques est limitée à ';
            app.lowerLimitValue = uieditfield(app.ParametersTab, 'text');
            app.lowerLimitValue.Tooltip = {'in mm.'};
            app.lowerLimitValue.Value = '100';
            app.Label_3 = uilabel(app.ParametersTab);
            app.Label_3.Text = 'La limite inférieur du déplacement des liaisons prismatiques est limitée à ';
            app.higherLimitValue = uieditfield(app.ParametersTab, 'text');
            app.higherLimitValue.Tooltip = {'in mm.'};
            app.higherLimitValue.Value = '100';
            app.Label_4 = uilabel(app.ParametersTab);
            app.Label_4.Text = 'Contraintes géométriques prises en compte pour les 6 position possible des actionneurs ?????';
            app.Label_5 = uilabel(app.ParametersTab);
            app.Label_5.Text = {'Cette application optimise le design d''un robot parallèle 3-RPR, suivant certaines contraintes.'; 'Les contraintes non cochées ne sont pas prises en compte dans l''optimisation.'};
            app.WorkspaceSamplingLabel = uilabel(app.ParametersTab);
            app.WorkspaceSamplingLabel.Text = 'Workspace Sampling:';
            app.MobileplatformerotationrangeLabel = uilabel(app.ParametersTab);
            app.MobileplatformerotationrangeLabel.Text = 'Mobile platforme rotation range';
            app.Spinner = uispinner(app.ParametersTab);
            app.Spinner_2 = uispinner(app.ParametersTab);
            app.toLabel = uilabel(app.ParametersTab);
            app.toLabel.Text = '° to';
            app.Label_6 = uilabel(app.ParametersTab);
            app.Label_6.Text = '°';
            app.SampletheworkspaceintoLabel = uilabel(app.ParametersTab);
            app.SampletheworkspaceintoLabel.Text = 'Sample the workspace into ';
            app.cerclesandLabel = uilabel(app.ParametersTab);
            app.cerclesandLabel.Text = 'cercles and ';
            app.equallyspacedsudivisionsLabel = uilabel(app.ParametersTab);
            app.equallyspacedsudivisionsLabel.Text = 'equally spaced sudivisions.';
            app.ncercles = uispinner(app.ParametersTab);
            app.ncercles.Limits = [1 Inf];
            app.ncercles.ValueChangedFcn = createCallbackFcn(app, @ncerclesValueChanged, true);
            app.ncercles.Value = 1;
            app.ndivs = uispinner(app.ParametersTab);
            app.ndivs.Limits = [4 Inf];
            app.ndivs.ValueChangedFcn = createCallbackFcn(app, @ndivsValueChanged, true);
            app.ndivs.Value = 4;
            app.samplingPreview = uiaxes(app.ParametersTab);
            title(app.samplingPreview, 'Sampling Preview')
            xlabel(app.samplingPreview, 'X (m)')
            ylabel(app.samplingPreview, 'Y(m)')
            app.OptimisationTab = uitab(app.TabGroup);
            app.OptimisationTab.Title = 'Optimisation';
            app.OptimisationparametersLabel = uilabel(app.OptimisationTab);
            app.OptimisationparametersLabel.Text = 'Optimisation parameters';
            app.ThesystemstatecontainsDdLabel = uilabel(app.OptimisationTab);
            app.ThesystemstatecontainsDdLabel.Text = 'The system state contains:  D, d';
            app.LowerboundsPanel = uipanel(app.OptimisationTab);
            app.LowerboundsPanel.Title = 'Lower bounds:';
            app.DLabel = uilabel(app.LowerboundsPanel);
            app.DLabel.Text = 'D = ';
            app.mLabel = uilabel(app.LowerboundsPanel);
            app.mLabel.Text = 'm';
            app.lb_D = uieditfield(app.LowerboundsPanel, 'numeric');
            app.lb_D.Value = 1;
            app.mLabel_2 = uilabel(app.LowerboundsPanel);
            app.mLabel_2.Text = 'm';
            app.lb_d = uieditfield(app.LowerboundsPanel, 'numeric');
            app.lb_d.Value = 0.1;
            app.dLabel = uilabel(app.LowerboundsPanel);
            app.dLabel.Text = 'd =';
            app.UpperboundsPanel = uipanel(app.OptimisationTab);
            app.UpperboundsPanel.Title = 'Upper bounds:';
            app.DLabel_2 = uilabel(app.UpperboundsPanel);
            app.DLabel_2.Text = 'D = ';
            app.mLabel_5 = uilabel(app.UpperboundsPanel);
            app.mLabel_5.Text = 'm';
            app.ub_D = uieditfield(app.UpperboundsPanel, 'numeric');
            app.ub_D.Value = 15;
            app.mLabel_6 = uilabel(app.UpperboundsPanel);
            app.mLabel_6.Text = 'm';
            app.ub_d = uieditfield(app.UpperboundsPanel, 'numeric');
            app.ub_d.Value = 5;
            app.dLabel_2 = uilabel(app.UpperboundsPanel);
            app.dLabel_2.Text = 'd =';
            app.InitialStatePanel = uipanel(app.OptimisationTab);
            app.InitialStatePanel.Title = 'Initial State:';
            app.DLabel_3 = uilabel(app.InitialStatePanel);
            app.DLabel_3.Text = 'D = ';
            app.mLabel_9 = uilabel(app.InitialStatePanel);
            app.mLabel_9.Text = 'm';
            app.D0 = uieditfield(app.InitialStatePanel, 'numeric');
            app.D0.Value = 10;
            app.mLabel_10 = uilabel(app.InitialStatePanel);
            app.mLabel_10.Text = 'm';
            app.d0 = uieditfield(app.InitialStatePanel, 'numeric');
            app.d0.Value = 1;
            app.dLabel_3 = uilabel(app.InitialStatePanel);
            app.dLabel_3.Text = 'd =';
            app.MaxiterationsLabel = uilabel(app.OptimisationTab);
            app.MaxiterationsLabel.Text = 'Max iterations:';
            app.MaxfunctionevaluationLabel = uilabel(app.OptimisationTab);
            app.MaxfunctionevaluationLabel.Text = 'Max function evaluation:';
            app.MaxIterations = uieditfield(app.OptimisationTab, 'numeric');
            app.MaxIterations.Value = 400;
            app.MaxEvals = uieditfield(app.OptimisationTab, 'numeric');
            app.MaxEvals.Value = 600;
            app.LogIterations = uicheckbox(app.OptimisationTab);
            app.LogIterations.Text = '';
            app.Label_7 = uilabel(app.OptimisationTab);
            app.Label_7.Text = 'Log iterations';
            app.RunOptimisationButton = uibutton(app.OptimisationTab, 'push');
            app.RunOptimisationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimisationButtonPushed, true);
            app.RunOptimisationButton.Text = 'Run Optimisation';
            app.Running = uilamp(app.OptimisationTab);
            app.Running.Enable = 'off';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup2 = uitabgroup(app.ResultsTab);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2_2 = uiaxes(app.Tab2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.ExitStatus = uilamp(app.ResultsTab);
            app.ExitStatus.Enable = 'off';
            app.SliderLabel = uilabel(app.ResultsTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ResultsTab);
            app.Slider_2Label = uilabel(app.ResultsTab);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.ResultsTab);
            app.KnobLabel = uilabel(app.ResultsTab);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.ResultsTab, 'continuous');
            app.ExitFlag = uilabel(app.ResultsTab);
            app.OptimaleConfigurationPanel = uipanel(app.ResultsTab);
            app.OptimaleConfigurationPanel.Title = 'Optimale Configuration';
            app.DLabel_4 = uilabel(app.OptimaleConfigurationPanel);
            app.DLabel_4.Text = 'D =';
            app.mLabel_13 = uilabel(app.OptimaleConfigurationPanel);
            app.mLabel_13.Text = 'm';
            app.X_D = uieditfield(app.OptimaleConfigurationPanel, 'numeric');
            app.mLabel_14 = uilabel(app.OptimaleConfigurationPanel);
            app.mLabel_14.Text = 'm';
            app.X_d = uieditfield(app.OptimaleConfigurationPanel, 'numeric');
            app.dLabel_4 = uilabel(app.OptimaleConfigurationPanel);
            app.dLabel_4.Text = 'd =';
            app.CurrentPositionLabel = uilabel(app.ResultsTab);
            app.CurrentPositionLabel.Text = 'Current Position:';
            app.XLabel = uilabel(app.ResultsTab);
            app.XLabel.Text = 'X=';
            app.EditField_19 = uieditfield(app.ResultsTab, 'numeric');
            app.cmLabel = uilabel(app.ResultsTab);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.ResultsTab);
            app.cmLabel_2.Text = 'cm';
            app.EditField_20 = uieditfield(app.ResultsTab, 'numeric');
            app.XLabel_2 = uilabel(app.ResultsTab);
            app.XLabel_2.Text = 'X=';
            app.cmLabel_3 = uilabel(app.ResultsTab);
            app.cmLabel_3.Text = 'cm';
            app.EditField_21 = uieditfield(app.ResultsTab, 'numeric');
            app.XLabel_3 = uilabel(app.ResultsTab);
            app.XLabel_3.Text = 'X=';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end